classdef Test_Prog_My_6_Var < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        ButtonGroup_4  matlab.ui.container.ButtonGroup
        Button_15      matlab.ui.control.Button
        Button_14      matlab.ui.control.RadioButton
        Button_13      matlab.ui.control.RadioButton
        ButtonGroup_3  matlab.ui.container.ButtonGroup
        Button_12      matlab.ui.control.RadioButton
        Button_11      matlab.ui.control.RadioButton
        Button_10      matlab.ui.control.RadioButton
        Button_9       matlab.ui.control.RadioButton
        ButtonGroup    matlab.ui.container.ButtonGroup
        cButton        matlab.ui.control.Button
        ButtonGroup_2  matlab.ui.container.ButtonGroup
        Button_8       matlab.ui.control.RadioButton
        Button_7       matlab.ui.control.RadioButton
        Button_4       matlab.ui.control.RadioButton
        Button_3       matlab.ui.control.RadioButton
        Button_2       matlab.ui.control.Button
        Button         matlab.ui.control.Button
        UIAxes4        matlab.ui.control.UIAxes
        UIAxes3        matlab.ui.control.UIAxes
        UIAxes2        matlab.ui.control.UIAxes
        UIAxes         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        T
        t
        Fs
        y % исходный сигнал
        z1 % filtered ECGnorm
        z2 % filtered ECGtahi
        N
        tmax
        ECGnorm
        ECGtahi
        btngrp
        g
        p
        h %учитывать аналогом g
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: Button, UIFigure
        function ButtonPushed(app, event)
             app.y=load(uigetfile('*.txt'));
             app.p = 15;
             app.g = 1;
             app.Fs=250;
             app.T=1/app.Fs;
             app.N=length(app.y);
             app.tmax=app.N*app.T;
             app.t=0:app.T:app.tmax-app.T;
             %plot(app.UIAxes,app.t,app.y)
             app.ECGnorm=app.y(1:app.N,1);
             app.ECGtahi=app.y(1:app.N,2);
             plot(app.UIAxes,app.t,app.ECGnorm)
             xlabel(app.UIAxes, 't,c')
             ylabel(app.UIAxes, 'ЭКГ, мВ')
             title(app.UIAxes, 'ЭКГ в норме с низкочастотной помехой')
             plot(app.UIAxes2,app.t,app.ECGtahi)
             xlabel(app.UIAxes2, 't,c')
             ylabel(app.UIAxes2, 'ЭКГ, мВ')
             title(app.UIAxes2, 'ЭКГ. Желудочковая тахикардия')


        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            cla(app.UIAxes3)
            cla(app.UIAxes4)
            load('coefficient_mat_file.mat')
            app.z1 = filter(Num, Den, app.ECGnorm);
            xlabel(app.UIAxes3, 't,c')
            ylabel(app.UIAxes3, 'ЭКГ, мВ')
            title(app.UIAxes3, 'Сигнал ЭКГ в норме после фильтрации')
            set(app.UIAxes3, 'XLim', [0 app.tmax])
            plot(app.UIAxes3,app.t,app.z1)
            plot(app.UIAxes,app.t,app.ECGnorm)
            xlabel(app.UIAxes, 't,c')
            ylabel(app.UIAxes, 'ЭКГ, мВ')
            title(app.UIAxes, 'ЭКГ в норме с низкочастотной помехой')
            set(app.UIAxes, 'XLim', [0 app.tmax])

            app.z2 = filter(Num, Den, app.ECGtahi);
            xlabel(app.UIAxes4, 't,c')
            ylabel(app.UIAxes4, 'ЭКГ, мВ')
            title(app.UIAxes4, 'Сигнал ЭКГ при тахикардии после фильтрации')
            set(app.UIAxes4, 'XLim', [0 app.tmax])
            plot(app.UIAxes4,app.t,app.z2)
            plot(app.UIAxes2,app.t,app.ECGtahi)
            xlabel(app.UIAxes2, 't,c')
            ylabel(app.UIAxes2, 'ЭКГ, мВ')
            title(app.UIAxes2, 'ЭКГ. Желудочковая тахикардия')
            set(app.UIAxes2, 'XLim', [0 app.tmax])

            %для параметров, которые учитываем заранее
            app.Button_13.Value
            app.Button_7.Value


        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if app.Button_3.Value
                app.g = 1;
                cla(app.UIAxes2)
                cla(app.UIAxes4)
                set(app.UIAxes3,'yscale','linear')
                set(app.UIAxes,'yscale','linear')
                xlabel(app.UIAxes3, 't,c')
                ylabel(app.UIAxes3, 'ЭКГ, мВ')
                title(app.UIAxes3, 'Сигнал ЭКГ в норме после фильтрации')
                plot(app.UIAxes3,app.t,app.z1)
                set(app.UIAxes, 'XLim', [0 app.tmax])
                set(app.UIAxes3, 'XLim', [0 app.tmax])
              
                plot(app.UIAxes,app.t,app.ECGnorm)
                xlabel(app.UIAxes, 't,c')
                ylabel(app.UIAxes, 'ЭКГ, мВ')
                title(app.UIAxes, 'ЭКГ в норме с низкочастотной помехой')
                if app.h ==1
                    set(app.UIAxes2,'yscale','linear')
                    set(app.UIAxes4,'yscale','linear')
                elseif app.h ==2
                    set(app.UIAxes2,'yscale','log')
                    set(app.UIAxes4,'yscale','log')
                end
            end 

            if app.Button_4.Value
                app.g = 2;
                cla(app.UIAxes)
                cla(app.UIAxes3)
                set(app.UIAxes2,'yscale','linear')
                set(app.UIAxes4,'yscale','linear')
                xlabel(app.UIAxes4, 't,c')
                ylabel(app.UIAxes4, 'ЭКГ, мВ')
                title(app.UIAxes4, 'Сигнал ЭКГ при тахикардии после фильтрации')
                plot(app.UIAxes4,app.t,app.z2)
                set(app.UIAxes2, 'XLim', [0 app.tmax])
                set(app.UIAxes4, 'XLim', [0 app.tmax])

                plot(app.UIAxes2,app.t,app.ECGtahi)
                xlabel(app.UIAxes2, 't,c')
                ylabel(app.UIAxes2, 'ЭКГ, мВ')
                title(app.UIAxes2, 'ЭКГ. Желудочковая тахикардия')

                if app.h ==1
                    set(app.UIAxes,'yscale','linear')
                    set(app.UIAxes3,'yscale','linear')
                elseif app.h ==2
                    set(app.UIAxes,'yscale','log')
                    set(app.UIAxes3,'yscale','log')
                end
            end


        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_2.SelectedObject;
            if app.Button_7.Value
                app.h = 1;
                if app.g == 1 
                    cla(app.UIAxes2)
                    cla(app.UIAxes4)
                    title(app.UIAxes4, 'Спектральная плотность мощности ЭКГ в норме после фильтрации')
                    title(app.UIAxes2, 'Спектральная плотность мощности ЭКГ в норме')
                    set(app.UIAxes2,'yscale','linear')
                    set(app.UIAxes4,'yscale','linear')
                
                elseif app.g == 2
                    cla(app.UIAxes3)
                    cla(app.UIAxes)
                    title(app.UIAxes, 'Спектральная плотность мощности ЭКГ при тахикардии')
                    title(app.UIAxes3, 'Спектральная плотность мощности ЭКГ при тахикардии после фильтрации')
                    set(app.UIAxes3,'yscale','linear')
                    set(app.UIAxes,'yscale','linear')
                    set(app.UIAxes, 'XLim', [0 app.tmax])
                    set(app.UIAxes3, 'XLim', [0 app.tmax])

                end
            end

            if app.Button_8.Value
                app.h = 2;
                if app.g == 2
                    cla(app.UIAxes3)
                    cla(app.UIAxes)
                    title(app.UIAxes, 'Спектральная плотность мощности ЭКГ при тахикардии')
                    title(app.UIAxes3, 'Спектральная плотность мощности ЭКГ при тахикардии после фильтрации')
                    set(app.UIAxes3,'yscale','log')
                    set(app.UIAxes,'yscale','log')
                elseif app.g == 1
                    cla(app.UIAxes2)
                    title(app.UIAxes2, 'Спектральная плотность мощности ЭКГ в норме')
                    cla(app.UIAxes4)
                    title(app.UIAxes4, 'Спектральная плотность мощности ЭКГ в норме после фильтрации')
                    set(app.UIAxes2,'yscale','log')
                    set(app.UIAxes4,'yscale','log')
                end
            end
        end

        % Button pushed function: cButton
        function cButtonPushed(app, event)

            if app.Button_3.Value
                [pxx, f] = pcov(app.ECGnorm, app.p, 2048, app.Fs);         
                plot(app.UIAxes2,f, pxx)
                title(app.UIAxes2, 'Спектральная плотность мощности ЭКГ в норме')
                xlabel(app.UIAxes2, 'f,Гц')
                ylabel(app.UIAxes2, 'СПМ, Вт*с')
                set(app.UIAxes2, 'XLim', [0, app.Fs/2])
                
                [pxx2, f2] = pcov(app.z1, app.p, 2048, app.Fs);
                plot(app.UIAxes4, f2, pxx2)
                title(app.UIAxes4, 'Спектральная плотность мощности ЭКГ в норме после фильтрации')
                xlabel(app.UIAxes4, 'f,Гц')
                ylabel(app.UIAxes4, 'СПМ, Вт*с')
                set(app.UIAxes4, 'XLim', [0, app.Fs/2])

                set(app.UIAxes3,'yscale','linear')
                xlabel(app.UIAxes3, 't,c')
                ylabel(app.UIAxes3, 'ЭКГ, мВ')
                title(app.UIAxes3, 'Сигнал ЭКГ в норме после фильтрации')
                plot(app.UIAxes3,app.t,app.z1)
                set(app.UIAxes3, 'XLim', [0 app.tmax])

                set(app.UIAxes,'yscale','linear')
                plot(app.UIAxes,app.t,app.ECGnorm)
                xlabel(app.UIAxes, 't,c')
                ylabel(app.UIAxes, 'ЭКГ, мВ')
                title(app.UIAxes, 'ЭКГ в норме с низкочастотной помехой')
                set(app.UIAxes, 'XLim', [0 app.tmax])
            end
            
            if app.Button_4.Value
                [pxx3, f3] = pcov(app.ECGtahi, app.p, 2048, app.Fs);
                plot(app.UIAxes, f3, pxx3)
                title(app.UIAxes, 'Спектральная плотность мощности ЭКГ при тахикардии')
                xlabel(app.UIAxes, 'f,Гц')
                ylabel(app.UIAxes, 'СПМ, Вт*с')
                set(app.UIAxes, 'XLim', [0, app.Fs/2])

                [pxx4, f4] = pcov(app.z2, app.p, 2048, app.Fs);
                plot(app.UIAxes3, f4, pxx4)
                title(app.UIAxes3, 'Спектральная плотность мощности ЭКГ при тахикардии после фильтрации')
                xlabel(app.UIAxes3, 'f,Гц')
                ylabel(app.UIAxes3, 'СПМ, Вт*с')
                set(app.UIAxes3, 'XLim', [0, app.Fs/2])

                set(app.UIAxes4,'yscale','linear')
                xlabel(app.UIAxes4, 't,c')
                ylabel(app.UIAxes4, 'ЭКГ, мВ')
                title(app.UIAxes4, 'Сигнал ЭКГ при тахикардии после фильтрации')
                set(app.UIAxes4, 'XLim', [0 app.tmax])
                plot(app.UIAxes4,app.t,app.z2)

                set(app.UIAxes2,'yscale','linear')
                plot(app.UIAxes2,app.t,app.ECGtahi)
                xlabel(app.UIAxes2, 't,c')
                ylabel(app.UIAxes2, 'ЭКГ, мВ')
                title(app.UIAxes2, 'ЭКГ. Желудочковая тахикардия')
                set(app.UIAxes2, 'XLim', [0 app.tmax])

            end

        end

        % Selection changed function: ButtonGroup_3
        function ButtonGroup_3SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_3.SelectedObject;

            if app.Button_9.Value
                app.p = 15;
            end

            if app.Button_10.Value
                app.p = 20;
            end

            if app.Button_11.Value
                app.p = 30;
            end

            if app.Button_12.Value
                app.p = 50;
            end


        end

        % Callback function
        function Button_13Pushed(app, event)

        end

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
            % АКФ
            tcf = -app.tmax/2:app.T:app.tmax/2;
            
            if app.Button_13.Value

                %length(app.N)
                %length(tcf)
                cla(app.UIAxes3)
                cla(app.UIAxes4)
                cla(app.UIAxes)
                cla(app.UIAxes2)

                set(app.UIAxes3,'yscale','linear')
                set(app.UIAxes4,'yscale','linear')
                set(app.UIAxes,'yscale','linear')
                set(app.UIAxes2,'yscale','linear')

                ACF = xcorr(app.ECGnorm,'coeff', round(app.N/2)); 
                ACF2 = xcorr(app.ECGtahi,'coeff', round(app.N/2));  

                plot(app.UIAxes,app.t,app.ECGnorm)
                xlabel(app.UIAxes, 't,c')
                ylabel(app.UIAxes, 'ЭКГ, мВ')
                title(app.UIAxes, 'ЭКГ в норме с низкочастотной помехой')
                set(app.UIAxes, 'XLim', [0 app.tmax])
                plot(app.UIAxes2,app.t,app.ECGtahi)
                xlabel(app.UIAxes2, 't,c')
                ylabel(app.UIAxes2, 'ЭКГ, мВ')
                title(app.UIAxes2, 'ЭКГ. Желудочковая тахикардия')
                set(app.UIAxes2, 'XLim', [0 app.tmax])
                %length(ACF)
                %length(ACF2)

                 
                plot(app.UIAxes3, tcf, ACF)
                title(app.UIAxes3, 'АКФ для ЭКГ в норме')
                set(app.UIAxes3, 'XLim', [-2.5 2.5])
               
                plot(app.UIAxes4, tcf, ACF2)
                title(app.UIAxes4, 'АКФ для ЭКГ при тахикардии')
                set(app.UIAxes4, 'XLim', [-2.5 2.5])
    
            end    
            

            if app.Button_14.Value
                cla(app.UIAxes)
                cla(app.UIAxes2)
                cla(app.UIAxes3)
                cla(app.UIAxes4)

                set(app.UIAxes,'yscale','linear')
                set(app.UIAxes2,'yscale','linear')
                set(app.UIAxes3,'yscale','linear')
                set(app.UIAxes4,'yscale','linear')

                xlabel(app.UIAxes3, 't,c')
                ylabel(app.UIAxes3, 'ЭКГ, мВ')
                title(app.UIAxes3, 'Сигнал ЭКГ в норме после фильтрации')
                set(app.UIAxes3, 'XLim', [0 app.tmax])
                plot(app.UIAxes3,app.t,app.z1)
                xlabel(app.UIAxes4, 't,c')
                ylabel(app.UIAxes4, 'ЭКГ, мВ')
                title(app.UIAxes4, 'Сигнал ЭКГ при тахикардии после фильтрации')
                plot(app.UIAxes4,app.t,app.z2)
                set(app.UIAxes4, 'XLim', [0 app.tmax])

                ACF = xcorr(app.z1,'coeff', round(app.N/2));  
                plot(app.UIAxes, tcf, ACF)
                title(app.UIAxes, 'АКФ для ЭКГ после фильтрации в норме')
                set(app.UIAxes, 'XLim', [-2.5 2.5])

                ACF2 = xcorr(app.z2,'coeff', round(app.N/2));  
                plot(app.UIAxes2, tcf, ACF2)
                title(app.UIAxes2, 'АКФ для ЭКГ после фильтрации при тахикардии')
                set(app.UIAxes2, 'XLim', [-2.5 2.5])
    
            end   


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Загрузить файл';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = 'Фильтрация';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Расчёт мощности';
            app.Button_3 = uiradiobutton(app.ButtonGroup);
            app.Button_3.Text = 'ЭКГ в норме';
            app.Button_3.Value = true;
            app.Button_4 = uiradiobutton(app.ButtonGroup);
            app.Button_4.Text = 'ЭКГ при тахикардии';
            app.ButtonGroup_2 = uibuttongroup(app.ButtonGroup);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.ButtonGroup_2.Title = 'Масштаб';
            app.Button_7 = uiradiobutton(app.ButtonGroup_2);
            app.Button_7.Text = 'Линейный';
            app.Button_7.Value = true;
            app.Button_8 = uiradiobutton(app.ButtonGroup_2);
            app.Button_8.Text = 'Логарифмический';
            app.cButton = uibutton(app.ButtonGroup, 'push');
            app.cButton.ButtonPushedFcn = createCallbackFcn(app, @cButtonPushed, true);
            app.cButton.Text = 'Раcсчитать';
            app.ButtonGroup_3 = uibuttongroup(app.UIFigure);
            app.ButtonGroup_3.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_3SelectionChanged, true);
            app.ButtonGroup_3.Title = 'Выбор порядка фильтрации';
            app.Button_9 = uiradiobutton(app.ButtonGroup_3);
            app.Button_9.Text = '15';
            app.Button_9.Value = true;
            app.Button_10 = uiradiobutton(app.ButtonGroup_3);
            app.Button_10.Text = '20';
            app.Button_11 = uiradiobutton(app.ButtonGroup_3);
            app.Button_11.Text = '30';
            app.Button_12 = uiradiobutton(app.ButtonGroup_3);
            app.Button_12.Text = '50';
            app.ButtonGroup_4 = uibuttongroup(app.UIFigure);
            app.ButtonGroup_4.Title = 'АКФ';
            app.Button_13 = uiradiobutton(app.ButtonGroup_4);
            app.Button_13.Text = 'Исходные сигналы';
            app.Button_13.Value = true;
            app.Button_14 = uiradiobutton(app.ButtonGroup_4);
            app.Button_14.Text = 'Сигналы после фильтрации';
            app.Button_15 = uibutton(app.ButtonGroup_4, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Text = 'Рассчитать';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Test_Prog_My_6_Var

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end